classdef IET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SHAPINGTOOLButton            matlab.ui.control.Button
        EQUILIBRIUMTOOLButton        matlab.ui.control.Button
        OPTIMIZATIONTOOLButton       matlab.ui.control.Button
        INVERSEEQUILIBRIUMTOOLLabel  matlab.ui.control.Label
        UserguideButton              matlab.ui.control.Button
        v112Label                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% IET (Inverse Equilibrium Tool) is a MATLAB computational tool constituted by three main modules, each with a dedicated GUI. It allows to compute the coil currents needed to obtain a predetermined plasma shape with defined plasma global parameters (i.e. total plasma current and total poloidal magnetic flux at the boundary) by solving a constrained minimization problem. 
% 
%     Copyright (C) 2019 Domenico Abate - domenico.abate@igi.cnr.it
% 
%     This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
            clear all
            close all
            clc
            addpath([pwd '/IET_SHAPING_TOOL/']);
            addpath([pwd '/IET_EQUILIBRIUM_TOOL/']);
            addpath([pwd '/IET_OPTIMIZATION_TOOL/']);
            clc
        end

        % Button pushed function: SHAPINGTOOLButton
        function SHAPINGTOOLButtonPushed(app, event)
            IET_SHAPING_TOOL_App
        end

        % Button pushed function: EQUILIBRIUMTOOLButton
        function EQUILIBRIUMTOOLButtonPushed(app, event)
  IET_EQUILIBRIUM_TOOL          
        end

        % Button pushed function: OPTIMIZATIONTOOLButton
        function OPTIMIZATIONTOOLButtonPushed(app, event)
            IET_OPTIMIZATION_TOOL
        end

        % Button pushed function: UserguideButton
        function UserguideButtonPushed(app, event)
% f = uifigure;
% message = sprintf(['Please visit http:\\ or contact domenico.abate@igi.cnr.it. The related PPCF publication can be found at: https://iopscience.iop.org/article/10.1088/1361-6587/ab3f09']);
% uialert(f,message,'Warning',...
% 'Icon','info');
%  winopen('IET_User_Manual_Document.pdf');
web('https://github.com/DA2412/IET/tree/master/Documentation')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SHAPINGTOOLButton = uibutton(app.UIFigure, 'push');
            app.SHAPINGTOOLButton.ButtonPushedFcn = createCallbackFcn(app, @SHAPINGTOOLButtonPushed, true);
            app.SHAPINGTOOLButton.Text = 'SHAPING TOOL';
            app.EQUILIBRIUMTOOLButton = uibutton(app.UIFigure, 'push');
            app.EQUILIBRIUMTOOLButton.ButtonPushedFcn = createCallbackFcn(app, @EQUILIBRIUMTOOLButtonPushed, true);
            app.EQUILIBRIUMTOOLButton.Text = 'EQUILIBRIUM TOOL';
            app.OPTIMIZATIONTOOLButton = uibutton(app.UIFigure, 'push');
            app.OPTIMIZATIONTOOLButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZATIONTOOLButtonPushed, true);
            app.OPTIMIZATIONTOOLButton.Text = 'OPTIMIZATION TOOL';
            app.INVERSEEQUILIBRIUMTOOLLabel = uilabel(app.UIFigure);
            app.INVERSEEQUILIBRIUMTOOLLabel.Text = 'INVERSE EQUILIBRIUM TOOL';
            app.UserguideButton = uibutton(app.UIFigure, 'push');
            app.UserguideButton.ButtonPushedFcn = createCallbackFcn(app, @UserguideButtonPushed, true);
            app.UserguideButton.Text = 'User guide';
            app.v112Label = uilabel(app.UIFigure);
            app.v112Label.Text = {'v. 1.1.2'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end