classdef IET_EQUILIBRIUM_TOOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IET_EQUIL_TOOL                  matlab.ui.Figure
        PlasmaparametersPanel           matlab.ui.container.Panel
        PoloidalFluxatboundaryLabel     matlab.ui.control.Label
        Flux_eq                         matlab.ui.control.NumericEditField
        PlasmacurrentLabel              matlab.ui.control.Label
        Iplasma_eq                      matlab.ui.control.NumericEditField
        WbLabel                         matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        LoadPlasmaShapeMeshBoundaryButton  matlab.ui.control.Button
        StaticEquilibrium               matlab.ui.container.TabGroup
        JplaProfilesTab                 matlab.ui.container.Tab
        LoadPButton                     matlab.ui.control.Button
        LoadFFButton                    matlab.ui.control.Button
        ProfilesPlot                    matlab.ui.control.UIAxes
        DefineJplaButton                matlab.ui.control.Button
        LoadPsibarButton                matlab.ui.control.Button
        ProfilesPlot_2                  matlab.ui.control.UIAxes
        JplaParametrizedTab             matlab.ui.container.Tab
        AlphaMLabel                     matlab.ui.control.Label
        Alpha_M                         matlab.ui.control.NumericEditField
        AlphaNLabel                     matlab.ui.control.Label
        Alpha_N                         matlab.ui.control.NumericEditField
        Beta0Label                      matlab.ui.control.Label
        Beta                            matlab.ui.control.NumericEditField
        R0Label                         matlab.ui.control.Label
        major_radius                    matlab.ui.control.NumericEditField
        DefineJplaButton_2              matlab.ui.control.Button
        JplaParamPlot                   matlab.ui.control.UIAxes
        JplaByPointsTab                 matlab.ui.container.Tab
        JplaArbitraryPlot               matlab.ui.control.UIAxes
        LoadJplaprofilebypointsButton   matlab.ui.control.Button
        DefineJplaButton_3              matlab.ui.control.Button
        PlasmaEquilibriumSolutionPanel  matlab.ui.container.Panel
        PlasmaSurfacesPlot              matlab.ui.control.UIAxes
        JprofileSolutionPlot            matlab.ui.control.UIAxes
        ConvergencePlot                 matlab.ui.control.UIAxes
        SaveEquilibriumButton           matlab.ui.control.Button
        SolveFixedBondaryEquilibriumButton  matlab.ui.control.Button
        TollerancePicardschemeEditFieldLabel  matlab.ui.control.Label
        TollerancePicardschemeEditField  matlab.ui.control.NumericEditField
        NumpointsspaceresolutionEditFieldLabel  matlab.ui.control.Label
        NumpointsspaceresolutionEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        tri
        node
        nodi_BCs
        Area_duale
        kk_nu
        mu0=4*pi*1.e-7;% Description
        psiBoundary
        Ipla
        AlphaM
        AlphaN
        Beta0
        R0
        JpType
        dP
        FdF
        psibar
        R_boundary_finale
        Z_boundary_finale
        EqSolution
        Jpoints
        ngrid
        tol
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% IET (Inverse Equilibrium Tool) is a MATLAB computational tool constituted by three main modules, each with a dedicated GUI. It allows to compute the coil currents needed to obtain a predetermined plasma shape with defined plasma global parameters (i.e. total plasma current and total poloidal magnetic flux at the boundary) by solving a constrained minimization problem. 
% 
%     Copyright (C) 2019 Domenico Abate - domenico.abate@igi.cnr.it
% 
%     This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
            clear all
            close all
            clc
            addpath([pwd '/funEquil/'])
            addpath(['IET_EQUILIBRIUM_TOOL/funEquil/'])
            clc
        end

        % Button pushed function: LoadPlasmaShapeMeshBoundaryButton
        function LoadPlasmaShapeMeshBoundaryButtonPushed(app, event)
            uiopen('load');
            app.tri = OutputShape.t;
            app.node = OutputShape.p;
            app.nodi_BCs=reshape(OutputShape.b,numel(OutputShape.b),1);
            plot(app.PlasmaSurfacesPlot,app.node(:,1),app.node(:,2),'*b');
            hold(app.PlasmaSurfacesPlot,'on')
            triplot(app.tri,app.node(:,1),app.node(:,2),'Parent',app.PlasmaSurfacesPlot)
            grid(app.PlasmaSurfacesPlot,'on')
            axis(app.PlasmaSurfacesPlot,'equal')

            app.R_boundary_finale = OutputShape.R_boundary_finale;
            app.Z_boundary_finale = OutputShape.Z_boundary_finale;
            
            if (isfield(OutputShape,'Area_duale') && isfield(OutputShape,'kk_nu'))
            
                app.Area_duale = OutputShape.Area_duale ;
                app.kk_nu= OutputShape.kk_nu;
            else                
            tic
            app.WaitMesh.Text = 'This could take few minutes...Take a coffee!';
            [app.Area_duale, app.kk_nu] = compute_dual_mesh(app,app.node,app.tri,app.mu0);
            app.WaitMesh.Text = '';
            toc
            
            OutputShape.Area_duale = app.Area_duale;
            OutputShape.kk_nu = app.kk_nu;
            save('IETShapingToolOutput.mat','OutputShape')
            end
            
        end

        % Value changed function: Flux_eq
        function Flux_eqValueChanged(app, event)
            app.psiBoundary = app.Flux_eq.Value;
        end

        % Value changed function: Iplasma_eq
        function Iplasma_eqValueChanged(app, event)
            app.Ipla = app.Iplasma_eq.Value;
        end

        % Value changed function: Alpha_M
        function Alpha_MValueChanged(app, event)
            app.AlphaM= app.Alpha_M.Value;
            
        end

        % Value changed function: Alpha_N
        function Alpha_NValueChanged(app, event)
            app.AlphaN= app.Alpha_N.Value;
            
        end

        % Value changed function: Beta
        function BetaValueChanged(app, event)
            app.Beta0= app.Beta.Value;
            
        end

        % Value changed function: major_radius
        function major_radiusValueChanged(app, event)
            app.R0 = app.major_radius.Value;

        end

        % Button pushed function: LoadPButton
        function LoadPButtonPushed(app, event)
            uiopen('load');
            app.dP = Jprofile.Pprime;
            app.JpType = 'Shafranov';
        end

        % Button pushed function: LoadFFButton
        function LoadFFButtonPushed(app, event)
            uiopen('load');
            app.FdF = Jprofile.FFprime;
            app.JpType = 'Shafranov';
            
        end

        % Button pushed function: SolveFixedBondaryEquilibriumButton
        function SolveFixedBondaryEquilibriumButtonPushed(app, event)
            app.tol = app.TollerancePicardschemeEditField.Value;
                        app.ngrid = app.NumpointsspaceresolutionEditField.Value;

            app.EqSolution = FBEquiSolver(app);
        end

        % Button pushed function: DefineJplaButton
        function DefineJplaButtonPushed(app, event)
        app.JpType = 'Shafranov';
        plot(app.ProfilesPlot,app.psibar,app.dP,'b-')
        grid(app.ProfilesPlot,'on')
        xlabel(app.ProfilesPlot,'Normalized \Psi')
        ylabel(app.ProfilesPlot,'dP')
        title(app.ProfilesPlot,'dP Profile')
        
        plot(app.ProfilesPlot_2,app.psibar,app.FdF,'r-')
        grid(app.ProfilesPlot_2,'on')
        xlabel(app.ProfilesPlot_2,'Normalized \Psi')
        ylabel(app.ProfilesPlot_2,'FdF')
        title(app.ProfilesPlot_2,'FdF Profile')
        end

        % Button pushed function: DefineJplaButton_2
        function DefineJplaButton_2Pushed(app, event)
            app.JpType = 'Blum';
            psilin = linspace(0,1,100);
            r = linspace(min(app.node(:,1)),max(app.node(:,1)),100);
             alpha_M = app.AlphaM;
           alpha_N = app.AlphaN;
            beta_0 = app.Beta0;
            R_0 = app.R0;
            g_psi=((1-psilin.^alpha_M).^alpha_N);
            h_f = (r*beta_0/R_0+R_0*(1-beta_0)./r);
             lambda=1;
            Jphi=lambda*h_f.*g_psi;
            plot(app.JplaParamPlot,psilin,Jphi,'o');
            xlabel(app.JplaParamPlot,'\Psi bar');
            ylabel(app.JplaParamPlot,'J_{\phi}')
        end

        % Button pushed function: DefineJplaButton_3
        function DefineJplaButton_3Pushed(app, event)
            app.JpType = 'Arbitrary';
            plot(app.JplaArbitraryPlot,app.psibar,app.Jpoints,'o')
            grid(app.JplaArbitraryPlot,'on')
        end

        % Button pushed function: LoadPsibarButton
        function LoadPsibarButtonPushed(app, event)
        uiopen('load');
        app.psibar = Jprofile.Psibar;
        end

        % Button pushed function: LoadJplaprofilebypointsButton
        function LoadJplaprofilebypointsButtonPushed(app, event)
            uiopen('load');
            app.psibar = JprofileArbitrary(:,1);
            app.Jpoints = JprofileArbitrary(:,2);
            
        end

        % Button pushed function: SaveEquilibriumButton
        function SaveEquilibriumButtonPushed(app, event)
            OutputEquil.Equil = app.EqSolution;  
                    OutputEquil.Equil.psiBoundary = app.psiBoundary;
        OutputEquil.Equil.Ipla = app.Ipla;
        
            OutputEquil.OutputShape.node = app.node;
            OutputEquil.OutputShape.tri = app.tri;
            OutputEquil.OutputShape.nodi_BCs = app.nodi_BCs;

            OutputEquil.OutputShape.R_boundary_finale = app.R_boundary_finale;
            OutputEquil.OutputShape.Z_boundary_finale = app.Z_boundary_finale;
            
            OutputEquil.OutputShape.Area_duale = app.Area_duale;
            OutputEquil.OutputShape.kk_nu = app.kk_nu;

            uisave({'OutputEquil'},'IETEquilibriumToolOutput');
        end

        % Value changed function: TollerancePicardschemeEditField
        function TollerancePicardschemeEditFieldValueChanged(app, event)
            app.tol = app.TollerancePicardschemeEditField.Value;
            
        end

        % Value changed function: NumpointsspaceresolutionEditField
        function NumpointsspaceresolutionEditFieldValueChanged(app, event)
            app.ngrid = app.NumpointsspaceresolutionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IET_EQUIL_TOOL = uifigure('Visible', 'off');
            app.IET_EQUIL_TOOL.Name = 'UI Figure';
            app.PlasmaparametersPanel = uipanel(app.IET_EQUIL_TOOL);
            app.PlasmaparametersPanel.Title = 'Plasma parameters';
            app.PoloidalFluxatboundaryLabel = uilabel(app.PlasmaparametersPanel);
            app.PoloidalFluxatboundaryLabel.Text = 'Poloidal Flux at boundary';
            app.Flux_eq = uieditfield(app.PlasmaparametersPanel, 'numeric');
            app.Flux_eq.ValueChangedFcn = createCallbackFcn(app, @Flux_eqValueChanged, true);
            app.PlasmacurrentLabel = uilabel(app.PlasmaparametersPanel);
            app.PlasmacurrentLabel.Text = 'Plasma current';
            app.Iplasma_eq = uieditfield(app.PlasmaparametersPanel, 'numeric');
            app.Iplasma_eq.ValueChangedFcn = createCallbackFcn(app, @Iplasma_eqValueChanged, true);
            app.WbLabel = uilabel(app.PlasmaparametersPanel);
            app.WbLabel.Text = 'Wb';
            app.ALabel = uilabel(app.PlasmaparametersPanel);
            app.ALabel.Text = 'A';
            app.LoadPlasmaShapeMeshBoundaryButton = uibutton(app.IET_EQUIL_TOOL, 'push');
            app.LoadPlasmaShapeMeshBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlasmaShapeMeshBoundaryButtonPushed, true);
            app.LoadPlasmaShapeMeshBoundaryButton.Text = 'Load Plasma Shape (Mesh & Boundary)';
            app.StaticEquilibrium = uitabgroup(app.IET_EQUIL_TOOL);
            app.JplaProfilesTab = uitab(app.StaticEquilibrium);
            app.JplaProfilesTab.Title = 'Shafranov P'',FF''';
            app.LoadPButton = uibutton(app.JplaProfilesTab, 'push');
            app.LoadPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPButtonPushed, true);
            app.LoadPButton.Text = 'Load P''';
            app.LoadFFButton = uibutton(app.JplaProfilesTab, 'push');
            app.LoadFFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFFButtonPushed, true);
            app.LoadFFButton.Text = 'Load FF''';
            app.ProfilesPlot = uiaxes(app.JplaProfilesTab);
            title(app.ProfilesPlot, 'Profiles')
            xlabel(app.ProfilesPlot, 'X')
            ylabel(app.ProfilesPlot, 'Y')
            app.DefineJplaButton = uibutton(app.JplaProfilesTab, 'push');
            app.DefineJplaButton.ButtonPushedFcn = createCallbackFcn(app, @DefineJplaButtonPushed, true);
            app.DefineJplaButton.Text = 'Define Jpla';
            app.LoadPsibarButton = uibutton(app.JplaProfilesTab, 'push');
            app.LoadPsibarButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPsibarButtonPushed, true);
            app.LoadPsibarButton.Text = 'Load Psibar';
            app.ProfilesPlot_2 = uiaxes(app.JplaProfilesTab);
            title(app.ProfilesPlot_2, 'Profiles')
            xlabel(app.ProfilesPlot_2, 'X')
            ylabel(app.ProfilesPlot_2, 'Y')
            app.JplaParametrizedTab = uitab(app.StaticEquilibrium);
            app.JplaParametrizedTab.Title = 'Parametrization';
            app.AlphaMLabel = uilabel(app.JplaParametrizedTab);
            app.AlphaMLabel.Text = 'Alpha M';
            app.Alpha_M = uieditfield(app.JplaParametrizedTab, 'numeric');
            app.Alpha_M.ValueChangedFcn = createCallbackFcn(app, @Alpha_MValueChanged, true);
            app.AlphaNLabel = uilabel(app.JplaParametrizedTab);
            app.AlphaNLabel.Text = 'Alpha N';
            app.Alpha_N = uieditfield(app.JplaParametrizedTab, 'numeric');
            app.Alpha_N.ValueChangedFcn = createCallbackFcn(app, @Alpha_NValueChanged, true);
            app.Beta0Label = uilabel(app.JplaParametrizedTab);
            app.Beta0Label.Text = 'Beta 0';
            app.Beta = uieditfield(app.JplaParametrizedTab, 'numeric');
            app.Beta.ValueChangedFcn = createCallbackFcn(app, @BetaValueChanged, true);
            app.R0Label = uilabel(app.JplaParametrizedTab);
            app.R0Label.Text = 'R0';
            app.major_radius = uieditfield(app.JplaParametrizedTab, 'numeric');
            app.major_radius.ValueChangedFcn = createCallbackFcn(app, @major_radiusValueChanged, true);
            app.DefineJplaButton_2 = uibutton(app.JplaParametrizedTab, 'push');
            app.DefineJplaButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefineJplaButton_2Pushed, true);
            app.DefineJplaButton_2.Text = 'Define Jpla';
            app.JplaParamPlot = uiaxes(app.JplaParametrizedTab);
            title(app.JplaParamPlot, 'Title')
            xlabel(app.JplaParamPlot, 'X')
            ylabel(app.JplaParamPlot, 'Y')
            app.JplaByPointsTab = uitab(app.StaticEquilibrium);
            app.JplaByPointsTab.Title = 'Arbitrary (by points)';
            app.JplaArbitraryPlot = uiaxes(app.JplaByPointsTab);
            title(app.JplaArbitraryPlot, 'Title')
            xlabel(app.JplaArbitraryPlot, 'X')
            ylabel(app.JplaArbitraryPlot, 'Y')
            app.LoadJplaprofilebypointsButton = uibutton(app.JplaByPointsTab, 'push');
            app.LoadJplaprofilebypointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadJplaprofilebypointsButtonPushed, true);
            app.LoadJplaprofilebypointsButton.Text = {'Load Jpla profile '; 'by points'};
            app.DefineJplaButton_3 = uibutton(app.JplaByPointsTab, 'push');
            app.DefineJplaButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefineJplaButton_3Pushed, true);
            app.DefineJplaButton_3.Text = 'Define Jpla';
            app.PlasmaEquilibriumSolutionPanel = uipanel(app.IET_EQUIL_TOOL);
            app.PlasmaEquilibriumSolutionPanel.Title = 'Plasma Equilibrium Solution';
            app.PlasmaSurfacesPlot = uiaxes(app.PlasmaEquilibriumSolutionPanel);
            title(app.PlasmaSurfacesPlot, 'Flux Surfaces')
            xlabel(app.PlasmaSurfacesPlot, 'X')
            ylabel(app.PlasmaSurfacesPlot, 'Y')
            app.JprofileSolutionPlot = uiaxes(app.PlasmaEquilibriumSolutionPanel);
            title(app.JprofileSolutionPlot, 'Jprofile solution')
            xlabel(app.JprofileSolutionPlot, 'X')
            ylabel(app.JprofileSolutionPlot, 'Y')
            app.ConvergencePlot = uiaxes(app.PlasmaEquilibriumSolutionPanel);
            title(app.ConvergencePlot, 'Convergence Picard')
            xlabel(app.ConvergencePlot, 'X')
            ylabel(app.ConvergencePlot, 'Y')
            app.SaveEquilibriumButton = uibutton(app.PlasmaEquilibriumSolutionPanel, 'push');
            app.SaveEquilibriumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEquilibriumButtonPushed, true);
            app.SaveEquilibriumButton.Text = 'Save Equilibrium';
            app.SolveFixedBondaryEquilibriumButton = uibutton(app.IET_EQUIL_TOOL, 'push');
            app.SolveFixedBondaryEquilibriumButton.ButtonPushedFcn = createCallbackFcn(app, @SolveFixedBondaryEquilibriumButtonPushed, true);
            app.SolveFixedBondaryEquilibriumButton.Text = 'Solve Fixed Bondary Equilibrium';
            app.TollerancePicardschemeEditFieldLabel = uilabel(app.IET_EQUIL_TOOL);
            app.TollerancePicardschemeEditFieldLabel.Text = 'Tollerance Picard scheme';
            app.TollerancePicardschemeEditField = uieditfield(app.IET_EQUIL_TOOL, 'numeric');
            app.TollerancePicardschemeEditField.ValueChangedFcn = createCallbackFcn(app, @TollerancePicardschemeEditFieldValueChanged, true);
            app.TollerancePicardschemeEditField.Value = 1e-11;
            app.NumpointsspaceresolutionEditFieldLabel = uilabel(app.IET_EQUIL_TOOL);
            app.NumpointsspaceresolutionEditFieldLabel.Text = 'Num points space resolution';
            app.NumpointsspaceresolutionEditField = uieditfield(app.IET_EQUIL_TOOL, 'numeric');
            app.NumpointsspaceresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @NumpointsspaceresolutionEditFieldValueChanged, true);
            app.NumpointsspaceresolutionEditField.Value = 100;
            % Show the figure after all components are created
            app.IET_EQUIL_TOOL.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IET_EQUILIBRIUM_TOOL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IET_EQUIL_TOOL)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IET_EQUIL_TOOL)
        end
    end
end