classdef IET_OPTIMIZATION_TOOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OptimizationPanel              matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SingleObjectiveTab             matlab.ui.container.Tab
        OptimizeButton                 matlab.ui.control.Button
        ConstrainedLeastSquareLSsolutionLabel  matlab.ui.control.Label
        PsiInvPlot                     matlab.ui.control.UIAxes
        PsiErrorPlot                   matlab.ui.control.UIAxes
        CurrentsPlot                   matlab.ui.control.UIAxes
        SaveresultsButton              matlab.ui.control.Button
        MultiObjectiveAOFmethodTab     matlab.ui.container.Tab
        NumParetopointsEditFieldLabel  matlab.ui.control.Label
        NumParetopointsEditField       matlab.ui.control.NumericEditField
        ParetoFront                    matlab.ui.control.UIAxes
        RunAOFmethodButton             matlab.ui.control.Button
        SaveAOFsolutionsButton         matlab.ui.control.Button
        MultiObjectiveNCmethodTab      matlab.ui.container.Tab
        NumberinitialpointsEditFieldLabel  matlab.ui.control.Label
        NumberinitialpointsEditField   matlab.ui.control.NumericEditField
        NumberofsolutionsEditFieldLabel  matlab.ui.control.Label
        NumberofsolutionsEditField     matlab.ui.control.NumericEditField
        NormalizedDesignSpace          matlab.ui.control.UIAxes
        RunNCmethodButton              matlab.ui.control.Button
        GeneralDesignSpace             matlab.ui.control.UIAxes
        SaveNCsolutionsButton          matlab.ui.control.Button
        LoadCoilsGeometryButton        matlab.ui.control.Button
        PrepareDataButton              matlab.ui.control.Button
        LoadEquilibriumButton          matlab.ui.control.Button
        psiBoundaryPlot                matlab.ui.control.UIAxes
        LoadEngineeringLimitsButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        OutputEquil
        coils % Description
        OptimDataIn
        %SOF
        LS_solution
        initPoints
        mSolUtopia 
        NC_solution
        nParetoPoints
        AOF_solution
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% IET (Inverse Equilibrium Tool) is a MATLAB computational tool constituted by three main modules, each with a dedicated GUI. It allows to compute the coil currents needed to obtain a predetermined plasma shape with defined plasma global parameters (i.e. total plasma current and total poloidal magnetic flux at the boundary) by solving a constrained minimization problem. 
% 
%     Copyright (C) 2019 Domenico Abate - domenico.abate@igi.cnr.it
% 
%     This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
            clear all
            close all
            clc
            addpath([pwd '/IET_OPTIMIZATION_TOOL/funOptim/']);
            addpath([pwd '/funOptim/'])
            clc
        end

        % Button pushed function: LoadCoilsGeometryButton
        function LoadCoilsGeometryButtonPushed(app, event)
            uiopen('load');
            app.coils = coils;
        end

        % Button pushed function: PrepareDataButton
        function PrepareDataButtonPushed(app, event)
        [app.OptimDataIn] = prepareInverseEquilibrium(app);
            
        end

        % Button pushed function: LoadEquilibriumButton
        function LoadEquilibriumButtonPushed(app, event)
            uiopen('load');
            app.OutputEquil = OutputEquil;
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            cla(app.CurrentsPlot)
            cla(app.PsiErrorPlot)
            cla(app.PsiInvPlot)
            [LS_solution] = LS_optimization(app);
            app.LS_solution = LS_solution;
        end

        % Button pushed function: LoadEngineeringLimitsButton
        function LoadEngineeringLimitsButtonPushed(app, event)
        uiopen('load');    
        app.OptimDataIn.LowerBounds = EngLimits.LB;
        app.OptimDataIn.UpperBounds = EngLimits.UB;
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
         Ls_solution = app.LS_solution;
         OptimDataIn = app.OptimDataIn;
        uisave({'Ls_solution','OptimDataIn'},'IET_SingleObjective_LS');

        end

        % Value changed function: NumberinitialpointsEditField
        function NumberinitialpointsEditFieldValueChanged(app, event)
            app.initPoints = app.NumberinitialpointsEditField.Value;
            
        end

        % Value changed function: NumberofsolutionsEditField
        function NumberofsolutionsEditFieldValueChanged(app, event)
            app.mSolUtopia = app.NumberofsolutionsEditField.Value;
            
        end

        % Button pushed function: RunNCmethodButton
        function RunNCmethodButtonPushed(app, event)
            cla(app.NormalizedDesignSpace)
            cla(app.GeneralDesignSpace)
            [app.NC_solution] = NC_optimization(app);
        end

        % Button pushed function: SaveNCsolutionsButton
        function SaveNCsolutionsButtonPushed(app, event)
        NC_solution = app.NC_solution;
         OptimDataIn = app.OptimDataIn;
        uisave({'NC_solution','OptimDataIn'},'IET_MultiObjective_NC.mat');

        end

        % Value changed function: NumParetopointsEditField
        function NumParetopointsEditFieldValueChanged(app, event)
            app.nParetoPoints = app.NumParetopointsEditField.Value;
            
        end

        % Button pushed function: RunAOFmethodButton
        function RunAOFmethodButtonPushed(app, event)
            cla(app.ParetoFront)
            [app.AOF_solution] = AOF_optimization(app);
        end

        % Button pushed function: SaveAOFsolutionsButton
        function SaveAOFsolutionsButtonPushed(app, event)
        AOF_solution = app.AOF_solution;
        OptimDataIn = app.OptimDataIn;
        uisave({'AOF_solution','OptimDataIn'},'IET_MultiObjective_AOF.mat');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.OptimizationPanel.Title = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationPanel);
            app.SingleObjectiveTab = uitab(app.TabGroup);
            app.SingleObjectiveTab.Title = 'Single Objective';
            app.OptimizeButton = uibutton(app.SingleObjectiveTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.ConstrainedLeastSquareLSsolutionLabel = uilabel(app.SingleObjectiveTab);
            app.ConstrainedLeastSquareLSsolutionLabel.Text = 'Constrained Least Square (LS) solution';
            app.PsiInvPlot = uiaxes(app.SingleObjectiveTab);
            title(app.PsiInvPlot, 'Title')
            xlabel(app.PsiInvPlot, 'X')
            ylabel(app.PsiInvPlot, 'Y')
            app.PsiErrorPlot = uiaxes(app.SingleObjectiveTab);
            title(app.PsiErrorPlot, 'Title')
            xlabel(app.PsiErrorPlot, 'X')
            ylabel(app.PsiErrorPlot, 'Y')
            app.CurrentsPlot = uiaxes(app.SingleObjectiveTab);
            title(app.CurrentsPlot, 'Title')
            xlabel(app.CurrentsPlot, 'X')
            ylabel(app.CurrentsPlot, 'Y')
            app.SaveresultsButton = uibutton(app.SingleObjectiveTab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.MultiObjectiveAOFmethodTab = uitab(app.TabGroup);
            app.MultiObjectiveAOFmethodTab.Title = 'MultiObjective AOF method';
            app.NumParetopointsEditFieldLabel = uilabel(app.MultiObjectiveAOFmethodTab);
            app.NumParetopointsEditFieldLabel.Text = 'Num Pareto points';
            app.NumParetopointsEditField = uieditfield(app.MultiObjectiveAOFmethodTab, 'numeric');
            app.NumParetopointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumParetopointsEditFieldValueChanged, true);
            app.ParetoFront = uiaxes(app.MultiObjectiveAOFmethodTab);
            title(app.ParetoFront, 'Title')
            xlabel(app.ParetoFront, 'X')
            ylabel(app.ParetoFront, 'Y')
            app.RunAOFmethodButton = uibutton(app.MultiObjectiveAOFmethodTab, 'push');
            app.RunAOFmethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunAOFmethodButtonPushed, true);
            app.RunAOFmethodButton.Text = 'Run AOF method';
            app.SaveAOFsolutionsButton = uibutton(app.MultiObjectiveAOFmethodTab, 'push');
            app.SaveAOFsolutionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAOFsolutionsButtonPushed, true);
            app.SaveAOFsolutionsButton.Text = 'Save AOF solutions';
            app.MultiObjectiveNCmethodTab = uitab(app.TabGroup);
            app.MultiObjectiveNCmethodTab.Title = 'MultiObjective NC method';
            app.NumberinitialpointsEditFieldLabel = uilabel(app.MultiObjectiveNCmethodTab);
            app.NumberinitialpointsEditFieldLabel.Text = 'Number initial points';
            app.NumberinitialpointsEditField = uieditfield(app.MultiObjectiveNCmethodTab, 'numeric');
            app.NumberinitialpointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberinitialpointsEditFieldValueChanged, true);
            app.NumberofsolutionsEditFieldLabel = uilabel(app.MultiObjectiveNCmethodTab);
            app.NumberofsolutionsEditFieldLabel.Text = 'Number of solutions';
            app.NumberofsolutionsEditField = uieditfield(app.MultiObjectiveNCmethodTab, 'numeric');
            app.NumberofsolutionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsolutionsEditFieldValueChanged, true);
            app.NormalizedDesignSpace = uiaxes(app.MultiObjectiveNCmethodTab);
            title(app.NormalizedDesignSpace, 'Normalized Design Metric Space')
            xlabel(app.NormalizedDesignSpace, 'X')
            ylabel(app.NormalizedDesignSpace, 'Y')
            app.RunNCmethodButton = uibutton(app.MultiObjectiveNCmethodTab, 'push');
            app.RunNCmethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunNCmethodButtonPushed, true);
            app.RunNCmethodButton.Text = 'Run NC method';
            app.GeneralDesignSpace = uiaxes(app.MultiObjectiveNCmethodTab);
            title(app.GeneralDesignSpace, 'General Design Metric Space')
            xlabel(app.GeneralDesignSpace, 'X')
            ylabel(app.GeneralDesignSpace, 'Y')
            app.SaveNCsolutionsButton = uibutton(app.MultiObjectiveNCmethodTab, 'push');
            app.SaveNCsolutionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNCsolutionsButtonPushed, true);
            app.SaveNCsolutionsButton.Text = 'Save NC solutions';
            app.LoadCoilsGeometryButton = uibutton(app.UIFigure, 'push');
            app.LoadCoilsGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilsGeometryButtonPushed, true);
            app.LoadCoilsGeometryButton.Text = 'Load Coils Geometry';
            app.PrepareDataButton = uibutton(app.UIFigure, 'push');
            app.PrepareDataButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareDataButtonPushed, true);
            app.PrepareDataButton.Text = 'Prepare Data';
            app.LoadEquilibriumButton = uibutton(app.UIFigure, 'push');
            app.LoadEquilibriumButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEquilibriumButtonPushed, true);
            app.LoadEquilibriumButton.Text = 'Load Equilibrium';
            app.psiBoundaryPlot = uiaxes(app.UIFigure);
            title(app.psiBoundaryPlot, 'Title')
            xlabel(app.psiBoundaryPlot, 'X')
            ylabel(app.psiBoundaryPlot, 'Y')
            app.LoadEngineeringLimitsButton = uibutton(app.UIFigure, 'push');
            app.LoadEngineeringLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEngineeringLimitsButtonPushed, true);
            app.LoadEngineeringLimitsButton.Text = 'Load Engineering Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IET_OPTIMIZATION_TOOL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end