classdef IET_SHAPING_TOOL_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ShapingTab                     matlab.ui.container.Tab
        PlasmaShape                    matlab.ui.control.UIAxes
        OUTERPanel                     matlab.ui.container.Panel
        REditFieldLabel                matlab.ui.control.Label
        Rou                            matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        Zou                            matlab.ui.control.NumericEditField
        INNERPanel                     matlab.ui.container.Panel
        REditFieldLabel_2              matlab.ui.control.Label
        Rin                            matlab.ui.control.NumericEditField
        ZEditFieldLabel_2              matlab.ui.control.Label
        Zin                            matlab.ui.control.NumericEditField
        UPPERPanel                     matlab.ui.container.Panel
        REditFieldLabel_3              matlab.ui.control.Label
        Rup                            matlab.ui.control.NumericEditField
        ZEditFieldLabel_3              matlab.ui.control.Label
        Zup                            matlab.ui.control.NumericEditField
        LOWERPanel                     matlab.ui.container.Panel
        REditFieldLabel_4              matlab.ui.control.Label
        Rlo                            matlab.ui.control.NumericEditField
        ZEditFieldLabel_4              matlab.ui.control.Label
        Zlo                            matlab.ui.control.NumericEditField
        RgeomLabel                     matlab.ui.control.Label
        RgeomEditField                 matlab.ui.control.Label
        ZgeomLabel                     matlab.ui.control.Label
        ZgeomEditField                 matlab.ui.control.Label
        ElongationLabel                matlab.ui.control.Label
        ElongationEditField            matlab.ui.control.Label
        UpperElongationLabel           matlab.ui.control.Label
        UpperElongationEditField       matlab.ui.control.Label
        LowerElongationLabel           matlab.ui.control.Label
        LowerElongationEditField       matlab.ui.control.Label
        TriangularityLabel             matlab.ui.control.Label
        TriangularityEditField         matlab.ui.control.Label
        UpperTriangularityLabel        matlab.ui.control.Label
        UpperTriangularityEditField    matlab.ui.control.Label
        LowerTriangularityLabel        matlab.ui.control.Label
        LowerTriangularityEditField    matlab.ui.control.Label
        MinorRadiusLabel               matlab.ui.control.Label
        MinorRadiusEditField           matlab.ui.control.Label
        HorizontalElongationLabel      matlab.ui.control.Label
        HorizontalElongationEditField  matlab.ui.control.Label
        LoadReferencePlasmaBoundaryButton  matlab.ui.control.Button
        GenerateBoundaryEllipse        matlab.ui.control.Button
        NumpointsEditFieldLabel        matlab.ui.control.Label
        NumpointsEditField             matlab.ui.control.NumericEditField
        ComputeshapeparametersButton   matlab.ui.control.Button
        XpointsButtonGroup             matlab.ui.container.ButtonGroup
        UpperSingleNullButton          matlab.ui.control.RadioButton
        LowerSingleNullButton          matlab.ui.control.RadioButton
        DoubleNullButton               matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        PlotBoundaryButton             matlab.ui.control.Button
        A1Label                        matlab.ui.control.Label
        B1Label                        matlab.ui.control.Label
        n1Label                        matlab.ui.control.Label
        A2Label                        matlab.ui.control.Label
        B2Label                        matlab.ui.control.Label
        n2Label                        matlab.ui.control.Label
        A3Label                        matlab.ui.control.Label
        B3Label                        matlab.ui.control.Label
        n3Label                        matlab.ui.control.Label
        A4Label                        matlab.ui.control.Label
        B4Label                        matlab.ui.control.Label
        n4Label                        matlab.ui.control.Label
        NoQuadrantLabel                matlab.ui.control.Label
        HorizontalAxisLabel            matlab.ui.control.Label
        VerticalAxisLabel              matlab.ui.control.Label
        ExponentLabel                  matlab.ui.control.Label
        ILabel                         matlab.ui.control.Label
        IILabel                        matlab.ui.control.Label
        IIILabel                       matlab.ui.control.Label
        IVLabel                        matlab.ui.control.Label
        MeshparametersPanel            matlab.ui.container.Panel
        TimeMesh                       matlab.ui.control.Label
        InfoLabel                      matlab.ui.control.Label
        InitialedgelengthEditField_3Label  matlab.ui.control.Label
        InitialedgelengthEditField     matlab.ui.control.NumericEditField
        ComputeprimaldualmeshButton    matlab.ui.control.Button
        SaveMeshBoundaryButton         matlab.ui.control.Button
        ShapingControlPanel            matlab.ui.container.Panel
        ShapeControlSwitchLabel        matlab.ui.control.Label
        ShapeControlSwitch             matlab.ui.control.ToggleSwitch
        Squareness1EditFieldLabel      matlab.ui.control.Label
        Squareness1EditField           matlab.ui.control.NumericEditField
        Squareness2EditFieldLabel      matlab.ui.control.Label
        Squareness2EditField           matlab.ui.control.NumericEditField
        Squareness3EditFieldLabel      matlab.ui.control.Label
        Squareness3EditField           matlab.ui.control.NumericEditField
        Squareness4EditFieldLabel      matlab.ui.control.Label
        Squareness4EditField           matlab.ui.control.NumericEditField
        UpdateshapeButton              matlab.ui.control.Button
        Squareness1KnobLabel           matlab.ui.control.Label
        Squareness1Knob                matlab.ui.control.Knob
        Squareness2KnobLabel           matlab.ui.control.Label
        Squareness2Knob                matlab.ui.control.Knob
        Squareness3KnobLabel           matlab.ui.control.Label
        Squareness3Knob                matlab.ui.control.Knob
        Squareness4KnobLabel           matlab.ui.control.Label
        Squareness4Knob                matlab.ui.control.Knob
    end

    
    properties (Access = private)
        rgeom % possono essere usate da tutti gli oggetti (bottoni ecc ecc)
        zgeom
        RZOU
        RZIN
        beta_0
        RZUP
        RZLO
        minor_radius
        elongation
        upper_elongation
        lower_elongation
        upper_triangularity
        lower_triangularity
        triangularity
        R_boundary_ref
        Z_boundary_ref
        Rfw
        Zfw
        r_ellipse
        z_ellipse
        A
        B
        k_first
        k_second
        k_third
        k_fourth
        num_points
        Z_limiter
        R_boundary_finale
        Z_boundary_finale
        flagBoundaryExists
        p
        t
        b
        kk_nu
        Area_duale
        mu0
    end
    
    methods (Access = private)
        
        function updatePlotCsiKnob1(app)
            %              app.Squareness1Knob.Value = app.Squareness1EditField.Value;
            %              app.Squareness2Knob.Value = app.Squareness2EditField.Value;
            %              app.Squareness3Knob.Value = app.Squareness3EditField.Value;
            %              app.Squareness4Knob.Value = app.Squareness4EditField.Value;
            
            csi(1,1) = app.Squareness1Knob.Value;
            csi(2,1) = app.Squareness2EditField.Value;
            csi(3,1) = app.Squareness3EditField.Value;
            csi(4,1) = app.Squareness4EditField.Value;
            
            app.Squareness1EditField.Value = csi(1,1);
            app.Squareness2EditField.Value = csi(2,1);
            app.Squareness3EditField.Value = csi(3,1);
            app.Squareness4EditField.Value = csi(4,1);
            
            [zs_smooth,~] = ComputeSuperEllipses(app,csi);
            
            plot(app.PlasmaShape,app.r_ellipse(1,:),zs_smooth(1,:),'c-','linewidth',2); %plot first quadrant ellipse
            
            app.Squareness1EditField.Value = csi(1,1);
        end
        function updatePlotCsiKnob2(app)
            
            csi(1,1) = app.Squareness1EditField.Value;
            csi(2,1) = app.Squareness2Knob.Value;
            csi(3,1) = app.Squareness3EditField.Value;
            csi(4,1) = app.Squareness4EditField.Value;
            
            app.Squareness1EditField.Value = csi(1,1);
            app.Squareness2EditField.Value = csi(2,1);
            app.Squareness3EditField.Value = csi(3,1);
            app.Squareness4EditField.Value = csi(4,1);
            
            [zs_smooth,~] = ComputeSuperEllipses(app,csi);
            
            plot(app.PlasmaShape,app.r_ellipse(2,:),zs_smooth(2,:),'c-','linewidth',2); %plot second quadrant ellipse
            
            app.Squareness2EditField.Value = csi(2,1);
        end
        
        function updatePlotCsiKnob3(app)
            
            csi(1,1) = app.Squareness1EditField.Value;
            csi(2,1) = app.Squareness2EditField.Value;
            csi(3,1) = app.Squareness3Knob.Value;
            csi(4,1) = app.Squareness4EditField.Value;
            
            app.Squareness1EditField.Value = csi(1,1);
            app.Squareness2EditField.Value = csi(2,1);
            app.Squareness3EditField.Value = csi(3,1);
            app.Squareness4EditField.Value = csi(4,1);
            
            [zs_smooth,~] = ComputeSuperEllipses(app,csi);
            
            plot(app.PlasmaShape,app.r_ellipse(3,:),zs_smooth(3,:),'c-','linewidth',2); %plot second quadrant ellipse
            
            app.Squareness3EditField.Value = csi(3,1);
        end
        
        function updatePlotCsiKnob4(app)
            
            csi(1,1) = app.Squareness1EditField.Value;
            csi(2,1) = app.Squareness2EditField.Value;
            csi(3,1) = app.Squareness3EditField.Value;
            csi(4,1) = app.Squareness4Knob.Value;
            
            app.Squareness1EditField.Value = csi(1,1);
            app.Squareness2EditField.Value = csi(2,1);
            app.Squareness3EditField.Value = csi(3,1);
            app.Squareness4EditField.Value = csi(4,1);
            
            [zs_smooth,~] = ComputeSuperEllipses(app,csi);
            
            plot(app.PlasmaShape,app.r_ellipse(4,:),zs_smooth(4,:),'c-','linewidth',2); %plot second quadrant ellipse
            
            app.Squareness4EditField.Value = csi(4,1);
        end
        
        function [zs_smooth,n_exponent] = ComputeSuperEllipses(app,csi)
            n_exponent = zeros(4,1);
            
            Rgeo = app.rgeom;
            a = app.minor_radius;      %minor radius
            Rzmax = app.RZUP(1);
            Rzmin = app.RZLO(1);
            Zrmax = app.RZOU(2);
            Zrmin = app.RZIN(2);
            Zoff14 = Zrmax;
            Zoff23 = Zrmin;
            
            epsi = a/Rgeo;     %inverse aspect ratio
            delta_u = (Rgeo-Rzmax)/a;    %upper triangularity
            delta_l = (Rgeo-Rzmin)/a;   %lower triangularity
            
            x(1,:) = app.r_ellipse(1,:)-a*(1/epsi-delta_u);                   %first quadrant upper outer
            n_exponent(1) = -log(2)/log(1/(2^0.5) + csi(1)*(1-1/(2^0.5))) ;
            y(1,:) = app.B(1)*(1-(x(1,:)/app.A(1)).^n_exponent(1)).^(1/n_exponent(1)); %explicit form of equation 14 in base y
            zs(1,:) = y(1,:)+Zoff14;
            
            x(2,:) = a*(1/epsi-delta_u)-app.r_ellipse(2,:);                   %second quadrant upper inner
            n_exponent(2) = -log(2)/log((0.5)^0.5 + csi(2)*(1-1/(2^0.5)));
            y(2,:) = app.B(2)*(1-(x(2,:)/app.A(2)).^n_exponent(2)).^(1/n_exponent(2));
            zs(2,:) = y(2,:)+Zoff23;
            
            x(3,:) = a*(1/epsi-delta_l)-app.r_ellipse(3,:);                   %third quadrant lower inner
            n_exponent(3) = -log(2)/log(1/(2^0.5) + csi(3)*(1-1/(2^0.5)));
            y(3,:) = app.B(3)*(1-(x(3,:)/app.A(3)).^n_exponent(3)).^(1/n_exponent(3));
            zs(3,:) = Zoff23-y(3,:);
            
            x(4,:) = -a*(1/epsi-delta_l)+app.r_ellipse(4,:);                   %fourth quadrant lower outer
            n_exponent(4) = -log(2)/log(1/(2^0.5) + csi(4)*(1-1/(2^0.5)));
            y(4,:) = app.B(4)*(1-(x(4,:)/app.A(4)).^n_exponent(4)).^(1/n_exponent(4));
            zs(4,:) = Zoff14-y(4,:);
            
            zs_smooth = real(zs);
            
            % plot superellipses for each quadrant
            plot(app.PlasmaShape,app.r_ellipse(1,:),zs_smooth(1,:),'c--','linewidth',2); %plot first quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(2,:),zs_smooth(2,:),'c--','linewidth',2); %plot second quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(3,:),zs_smooth(3,:),'c--','linewidth',2);%plot third quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(4,:),zs_smooth(4,:),'c--','linewidth',2);%plot fourth quadrant ellipse
            
            app.A1Label.Text = num2str(app.A(1));
            app.A2Label.Text = num2str(app.A(2));
            app.A3Label.Text = num2str(app.A(3));
            app.A4Label.Text = num2str(app.A(4));
            app.B1Label.Text = num2str(app.B(1));
            app.B2Label.Text = num2str(app.B(2));
            app.B3Label.Text = num2str(app.B(3));
            app.B4Label.Text = num2str(app.B(4));
            
            app.n1Label.Text = num2str(n_exponent(1));
            app.n2Label.Text = num2str(n_exponent(2));
            app.n3Label.Text = num2str(n_exponent(3));
            app.n4Label.Text = num2str(n_exponent(4));
            
        end
        
        function  computeShapeParameters(app)
            
            app.rgeom=.5*(app.RZOU(1)+app.RZIN(1));
            app.zgeom=.5*(app.RZOU(2)+app.RZIN(2));
            major_radius=app.rgeom;
            app.minor_radius=major_radius-app.RZIN(1);
            app.upper_elongation=(app.RZUP(2)-app.zgeom)/(app.RZOU(1)-app.rgeom);
            app.lower_elongation=(app.zgeom-app.RZLO(2))/(app.RZOU(1)-app.rgeom);
            app.elongation=(app.RZUP(2)-app.RZLO(2))/(app.RZOU(1)-app.RZIN(1));
            horizontal_elongation = 1/app.elongation;
            app.upper_triangularity=(app.rgeom-app.RZUP(1))/(app.rgeom-app.RZIN(1));
            app.lower_triangularity=(app.rgeom-app.RZLO(1))/(app.rgeom-app.RZIN(1));
            app.triangularity=(app.rgeom-.5*app.RZUP(1)-.5*app.RZLO(1))/(app.rgeom-app.RZIN(1));
            
            app.RgeomEditField.Text = num2str(app.rgeom);
            app.ZgeomEditField.Text = num2str(app.zgeom);
            app.MinorRadiusEditField.Text = num2str(app.minor_radius);
            app.ElongationEditField.Text = num2str(app.elongation);
            app.UpperElongationEditField.Text = num2str(app.upper_elongation);
            app.LowerElongationEditField.Text = num2str(app.lower_elongation);
            app.HorizontalElongationEditField.Text = num2str(horizontal_elongation);
            app.TriangularityEditField.Text = num2str(app.triangularity);
            app.UpperTriangularityEditField.Text = num2str(app.upper_triangularity);
            app.LowerTriangularityEditField.Text = num2str(app.lower_triangularity);
        end
        
        function computeEllipseBoundary(app)
            num = app.num_points;
            a = app.minor_radius;      %minor radius
            Rmax = app.RZOU(1);
            Rmin = app.RZIN(1);
            Zmax = app.RZUP(2);
            Zmin = app.RZLO(2);
            Rzmax = app.RZUP(1);
            Rzmin = app.RZLO(1);
            Zrmax = app.RZOU(2);
            Zrmin = app.RZIN(2);
            Rgeo = app.rgeom;
            delta_u = (Rgeo-Rzmax)/a;    %upper triangularity
            delta_l = (Rgeo-Rzmin)/a;   %lower triangularity
            
            %% defining new elongations for each quadrant
            Zoff14 = Zrmax;
            Zoff23 = Zrmin;
            
            app.k_first = (Zmax-Zoff14)/a;
            app.k_second = (Zmax-Zoff23)/a;
            app.k_third = (Zoff23-Zmin)/a;
            app.k_fourth = (Zoff14-Zmin)/a;
            
            r = zeros(4,num);
            z = zeros(4,num);
            app.A = zeros(4,1);
            app.B = zeros(4,1);
            
            app.A(1) = a*(1+delta_u) ;
            app.B(1) = app.k_first*a;
            r(1,:) = linspace(Rmax, Rzmax, num);
            z(1,:) = Zoff14+app.B(1)*(1-((r(1,:)-Rzmax)/app.A(1)).^2).^0.5;
            
            r(4,:) = linspace(Rzmin, Rmax, num);
            app.A(4) = a*(1+delta_l);
            app.B(4) = app.k_fourth*a;
            z(4,:) = Zoff14-app.B(4)*(1-((r(4,:)-Rzmin)/app.A(4)).^2).^0.5;
            
            app.A(2) = a*(1-delta_u) ;
            app.B(2) = app.k_second*a;
            r(2,:) = linspace(Rzmax, Rmin, num);
            z(2,:) = Zoff23+app.B(2)*(1-((-r(2,:)+Rzmax)/app.A(2)).^2).^0.5;
            
            app.A(3) = a*(1-delta_l);
            app.B(3) = app.k_third*a;
            r(3,:)=linspace(Rmin, Rzmin, num);
            z(3,:) = Zoff23-app.B(3)*(1-((-r(3,:)+Rzmin)/app.A(3)).^2).^0.5;
            
            app.r_ellipse=real(r);
            app.z_ellipse=real(z);
        end
        
        function [zs_Xpoint] = computeSuperellipsesXpoint(app)
            csi(1,1) = app.Squareness1EditField.Value;
            csi(2,1) = app.Squareness2EditField.Value;
            csi(3,1) = app.Squareness3EditField.Value;
            csi(4,1) = app.Squareness4EditField.Value;
            
            [~,n_exponent] = ComputeSuperEllipses(app,csi);
            
            Rgeo = app.rgeom;
            a = app.minor_radius;      %minor radius
            Rzmax = app.RZUP(1);
            Rzmin = app.RZLO(1);
            Zrmax = app.RZOU(2);
            Zrmin = app.RZIN(2);
            Zoff14 = Zrmax;
            Zoff23 = Zrmin;
            
            epsi = a/Rgeo;     %inverse aspect ratio
            delta_u = (Rgeo-Rzmax)/a;    %upper triangularity
            delta_l = (Rgeo-Rzmin)/a;   %lower triangularity
            
            x0 = [app.A(:,1) app.B(:,1) n_exponent(:,1)];
            first=real(fsolve(@(s)constraints1(a,delta_u,app.k_first,csi,s),x0(1,:)));%first quad
            second=real(fsolve(@(s)constraints2(a,delta_u,app.k_second,csi,s),x0(2,:)));%second quad
            third=real(fsolve(@(s)constraints3(a,delta_l,app.k_third,csi,s),x0(3,:)));%third quad
            fourth=real(fsolve(@(s)constraints4(a,delta_l,app.k_fourth,csi,s),x0(4,:)));%fourth quad
            
            alpha=[first(1);second(1);third(1);fourth(1)];
            beta=[first(2);second(2);third(2);fourth(2)];
            n_exponent_Xpoint=[first(3);second(3);third(3);fourth(3)];
            
            X(1,:)= +app.r_ellipse(1,:)-a*(1/epsi+1)+alpha(1);                %first quadrant upper outer
            y(1,:) =  beta(1)*(1-(X(1,:)/alpha(1)).^n_exponent_Xpoint(1)).^(1/n_exponent_Xpoint(1)) ; %explicit form of equation 14 in base y
            zs(1,:) = y(1,:)+Zoff14;
            
            
            X(2,:)=a*(1/epsi-1)+alpha(2)-app.r_ellipse(2,:);
            y(2,:) = beta(2)*(1-(X(2,:)/alpha(2)).^n_exponent_Xpoint(2)).^(1/n_exponent_Xpoint(2)) ;
            zs(2,:) = y(2,:)+Zoff23;
            %plot(r(2,:),zs(2,:),'-r','linewidth',2); %plot second quadrant ellipse
            
            %r(3,:)=linspace(Rmin,Rzmin+5/100*Rzmin,num);
            X(3,:)=a*(1/epsi-1)+alpha(3)-app.r_ellipse(3,:); %sostituito r*a con r
            y(3,:) =  beta(3)*(1-(X(3,:)/alpha(3)).^n_exponent_Xpoint(3)).^(1/n_exponent_Xpoint(3)) ;
            zs(3,:) = Zoff23-y(3,:);
            
            %r(4,:) = linspace(Rmax, Rzmin-5/100*Rzmin, num);
            X(4,:)= +app.r_ellipse(4,:) -a*(1/epsi+1) +alpha(4);
            %X(4,:)= +r(4,:) -a*(1/epsi-1) -alpha(4);
            y(4,:) =  beta(4)*(1-(X(4,:)/alpha(4)).^n_exponent_Xpoint(4)).^(1/n_exponent_Xpoint(4)) ;
            zs(4,:) = Zoff14 - y(4,:);
            
            zs_Xpoint = real(zs);
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% IET (Inverse Equilibrium Tool) is a MATLAB computational tool constituted by three main modules, each with a dedicated GUI. It allows to compute the coil currents needed to obtain a predetermined plasma shape with defined plasma global parameters (i.e. total plasma current and total poloidal magnetic flux at the boundary) by solving a constrained minimization problem. 
% 
%     Copyright (C) 2019 Domenico Abate - domenico.abate@igi.cnr.it
% 
%     This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.
clear all
close all
clc
addpath([pwd '/IET_SHAPING_TOOL/functionShaping']);
addpath([pwd '/IET_SHAPING_TOOL/distmeshModGui']);
addpath([pwd '/functionShaping']);
addpath([pwd '/distmeshModGui']);
clc
        end

        % Button pushed function: LoadReferencePlasmaBoundaryButton
        function LoadReferencePlasmaBoundaryButtonPushed(app, event)
            cla(app.PlasmaShape)
            uiopen('load');
            app.flagBoundaryExists = 1;
            app.R_boundary_ref = geometry.boundaryCoordinates(:,1);
            app.Z_boundary_ref = geometry.boundaryCoordinates(:,2);
            hold(app.PlasmaShape,'on')
            
            if isfield(geometry,'FW')
            app.Rfw = geometry.FW(:,1);
            app.Zfw=  geometry.FW(:,2);
            hold(app.PlasmaShape,'on')
            plot(app.PlasmaShape,app.Rfw,app.Zfw,'k-','linewidth',3)
            hold(app.PlasmaShape,'on')
            grid(app.PlasmaShape,'on')
            axis(app.PlasmaShape,'equal')
            end
            
            plot(app.PlasmaShape,app.R_boundary_ref,app.Z_boundary_ref,'m--','linewidth',2)
            hold(app.PlasmaShape,'on')
            
            [app.Rou.Value, iout] = max(app.R_boundary_ref);
            app.Zou.Value = app.Z_boundary_ref(iout);
            
            [app.Rin.Value, iin] = min(app.R_boundary_ref);
            app.Zin.Value = app.Z_boundary_ref(iin);
            
            [app.Zup.Value, iup] = max(app.Z_boundary_ref);
            app.Rup.Value = app.R_boundary_ref(iup);
            
            [app.Zlo.Value, ilo] = min(app.Z_boundary_ref);
            app.Rlo.Value = app.R_boundary_ref(ilo);
            
            app.RZOU(1) = app.Rou.Value;
            app.RZOU(2) = app.Zou.Value;
            app.RZIN(1)= app.Rin.Value;
            app.RZIN(2) = app.Zin.Value;
            app.RZUP(1) = app.Rup.Value;
            app.RZUP(2) = app.Zup.Value;
            app.RZLO(1) = app.Rlo.Value;
            app.RZLO(2) = app.Zlo.Value;
            
            computeShapeParameters(app);
            
            plot(app.PlasmaShape,app.RZOU(1),app.RZOU(2),'o',app.RZIN(1),app.RZIN(2),'o',app.RZUP(1),app.RZUP(2),'o',app.RZLO(1),app.RZLO(2),'o')
            set(app.PlasmaShape,'linewidth',2)
            grid(app.PlasmaShape,'on')
            axis(app.PlasmaShape,'equal')
            
        end

        % Button pushed function: GenerateBoundaryEllipse
        function GenerateBoundaryEllipsePushed(app, event)
            cla(app.PlasmaShape);
            ComputeshapeparametersButtonPushed(app);
            app.num_points = app.NumpointsEditField.Value;
            
            computeEllipseBoundary(app);
            
            
            plot(app.PlasmaShape,app.r_ellipse(1,:),app.z_ellipse(1,:),'-r','linewidth',1); %plot first quadrant ellipse
            hold(app.PlasmaShape,'on')
            plot(app.PlasmaShape,app.r_ellipse(2,:),app.z_ellipse(2,:),'-r','linewidth',1); %plot second quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(3,:),app.z_ellipse(3,:),'-r','linewidth',1);%plot third quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(4,:),app.z_ellipse(4,:),'-r','linewidth',1);%plot fourth quadrant ellipse

            
            if app.flagBoundaryExists == 0
                app.R_boundary_ref = [app.r_ellipse(1,:),app.r_ellipse(2,:),app.r_ellipse(3,:),app.r_ellipse(4,:)]';
                app.Z_boundary_ref = [app.z_ellipse(1,:),app.z_ellipse(2,:),app.z_ellipse(3,:),app.z_ellipse(4,:)]';
            end
                        plot(app.PlasmaShape,app.R_boundary_ref,app.Z_boundary_ref,'m--','linewidth',2)
            hold(app.PlasmaShape,'off')

        end

        % Button pushed function: ComputeshapeparametersButton
        function ComputeshapeparametersButtonPushed(app, event)
            app.RZOU(1) = app.Rou.Value;
            app.RZOU(2) = app.Zou.Value;
            app.RZIN(1)= app.Rin.Value;
            app.RZIN(2) = app.Zin.Value;
            app.RZUP(1) = app.Rup.Value;
            app.RZUP(2) = app.Zup.Value;
            app.RZLO(1) = app.Rlo.Value;
            app.RZLO(2) = app.Zlo.Value;
            cla(app.PlasmaShape);
            
            if (isempty(app.Rfw)==0)
                    plot(app.PlasmaShape,app.Rfw,app.Zfw,'k-','linewidth',3)
            end
            
            plot(app.PlasmaShape,app.RZOU(1),app.RZOU(2),'o',app.RZIN(1),app.RZIN(2),'o',app.RZUP(1),app.RZUP(2),'o',app.RZLO(1),app.RZLO(2),'o')
            set(app.PlasmaShape,'linewidth',2)
            grid(app.PlasmaShape,'on')
            
            computeShapeParameters(app);
            
        end

        % Value changed function: ShapeControlSwitch
        function ShapeControlSwitchValueChanged(app, event)
            value = app.ShapeControlSwitch.Value;
            
            if strcmp(value, 'On')
                app.num_points = app.NumpointsEditField.Value;
                num = app.num_points;
                computeEllipseBoundary(app);
                
                Rgeo = app.rgeom;
                a = app.minor_radius;      %minor radius
                Rmax = app.RZOU(1);
                Rmin = app.RZIN(1);
                Zmax = app.RZUP(2);
                Zmin = app.RZLO(2);
                Rzmax = app.RZUP(1);
                Rzmin = app.RZLO(1);
                Zrmax = app.RZOU(2);
                Zrmin = app.RZIN(2);
                Zoff14 = Zrmax;
                Zoff23 = Zrmin;
                
                epsi = a/Rgeo;     %inverse aspect ratio
                delta_u = (Rgeo-Rzmax)/a;    %upper triangularity
                delta_l = (Rgeo-Rzmin)/a;   %lower triangularity
                
                
                E = zeros(4,2);   %extremal quadrant point matrix
                O = zeros(4,2);   %center quadrant point matrix
                C = zeros(4,2);   %intersection point between diagonal and ellipse matrix
                D = zeros(4,2);   %intersection point between diagonal and real shape matrix
                Rdiag = zeros(4,num); %R diagonal coordinates
                Zdiag = zeros(4,num); %Z diagonal coordinates
                
                E(1,:) = [Rmax Zmax];               %first quadrant
                O(1,:) = [Rzmax Zoff14];
                Rdiag(1,:) = linspace(O(1,1),Rmax,num);
                Zdiag(1,:) = O(1,2)+(E(1,2)-O(1,2))/(E(1,1)-O(1,1))*(Rdiag(1,:)-O(1,1));
                [RC,ZC] = intersections(Rdiag(1,:),Zdiag(1,:),app.r_ellipse(1,:),app.z_ellipse(1,:));  %calculation of intersections between ellipse and diagonal
                C(1,1) = RC;
                C(1,2) = ZC;
                [RD,ZD] = intersections(Rdiag(1,:),Zdiag(1,:),app.R_boundary_ref,app.Z_boundary_ref); %calculation on fintersections between real shape and diagonal
                D(1,1) = RD;
                D(1,2) = ZD;
                
                E(2,:) = [Rmin Zmax];   %second quadrant
                O(2,:) = [Rzmax Zoff23];
                Rdiag(2,:) = linspace(Rmin,O(2,1),num);
                Zdiag(2,:) = O(2,2)+(E(2,2)-O(2,2))/(E(2,1)-O(2,1))*(Rdiag(2,:)-O(2,1));
                [RC,ZC] = intersections(Rdiag(2,:),Zdiag(2,:),app.r_ellipse(2,:),app.z_ellipse(2,:));  %calculation of intersections between ellipse and diagonal
                C(2,1) = RC;
                C(2,2) = ZC;
                [RD,ZD] = intersections(Rdiag(2,:),Zdiag(2,:),app.R_boundary_ref,app.Z_boundary_ref); %calculation on fintersections between real shape and diagonal
                D(2,1) = RD;
                D(2,2) = ZD;
                
                E(3,:) = [Rmin Zmin];       %third quadrant
                O(3,:) = [Rzmin Zoff23];
                Rdiag(3,:) = linspace(Rmin,O(3,1),num);
                Zdiag(3,:) = O(3,2)+(E(3,2)-O(3,2))/(E(3,1)-O(3,1))*(Rdiag(3,:)-O(3,1)); %defining diagonal between point O and point E
                [RC,ZC] = intersections(Rdiag(3,:),Zdiag(3,:),app.r_ellipse(3,:),app.z_ellipse(3,:));  %calculation of intersections between ellipse and diagonal
                C(3,1) = RC;
                C(3,2) = ZC;
                [RD,ZD] = intersections(Rdiag(3,:),Zdiag(3,:),app.R_boundary_ref,app.Z_boundary_ref); %calculation on fintersections between real shape and diagonal
                D(3,1) = RD;
                D(3,2) = ZD;
                
                E(4,:) = [Rmax Zmin];   %fourth quadrant
                O(4,:) = [Rzmin Zoff14];
                Rdiag(4,:) = linspace(O(4,1),Rmax,num);
                Zdiag(4,:) = O(4,2)+(E(4,2)-O(4,2))/(E(4,1)-O(4,1))*(Rdiag(4,:)-O(4,1)); %defining diagonal between point O and point E
                [RC,ZC] = intersections(Rdiag(4,:),Zdiag(4,:),app.r_ellipse(4,:),app.z_ellipse(4,:));  %calculation of intersections between ellipse and diagonal
                C(4,1) = RC;
                C(4,2) = ZC;
                [RD,ZD] = intersections(Rdiag(4,:),Zdiag(4,:),app.R_boundary_ref,app.Z_boundary_ref); %calculation on fintersections between real shape and diagonal
                D(4,1) = RD;
                D(4,2) = ZD;
                
                %% distances calculation for each quadrant
                dOE(1) = pdist2(O(1,:),E(1,:),'euclidean');%first quadrant
                dOD(1) = pdist2(O(1,:),D(1,:),'euclidean');
                dOC(1) = pdist2(O(1,:),C(1,:),'euclidean');
                
                dOE(2) = pdist2(O(2,:),E(2,:),'euclidean');%second quadrant
                dOD(2) = pdist2(O(2,:),D(2,:),'euclidean');
                dOC(2) = pdist2(O(2,:),C(2,:),'euclidean');
                
                dOE(3) = pdist2(O(3,:),E(3,:),'euclidean');%third quadrant
                dOD(3) = pdist2(O(3,:),D(3,:),'euclidean');
                dOC(3) = pdist2(O(3,:),C(3,:),'euclidean');
                
                dOE(4) = pdist2(O(4,:),E(4,:),'euclidean');%fourth quadrant
                dOD(4) = pdist2(O(4,:),D(4,:),'euclidean');
                dOC(4) = pdist2(O(4,:),C(4,:),'euclidean');
                
                hold(app.PlasmaShape,'on')

                plot(app.PlasmaShape,O(1,1),O(1,2),'o','markersize',10,'markerfacecolor','k');
                plot(app.PlasmaShape,O(2,1),O(2,2),'o','markersize',10,'markerfacecolor','b');
                plot(app.PlasmaShape,O(3,1),O(3,2),'o','markersize',10,'markerfacecolor','g');
                plot(app.PlasmaShape,O(4,1),O(4,2),'o','markersize',10,'markerfacecolor','r');
                text(app.PlasmaShape,O(1,1),O(1,2),'  O1','FontSize',11);
                text(app.PlasmaShape,O(2,1),O(2,2),'  O2','FontSize',11);
                text(app.PlasmaShape,O(3,1),O(3,2),'O3   ','HorizontalAlignment','right','FontSize',11);
                text(app.PlasmaShape,O(4,1),O(4,2),'O4   ','HorizontalAlignment','right','FontSize',11);
                
                plot(app.PlasmaShape,E(1,1),E(1,2),'.')
                text(app.PlasmaShape,E(1,1),E(1,2),' E1','FontSize',12)
                plot(app.PlasmaShape,Rdiag(1,:),Zdiag(1,:),'k')
                plot (app.PlasmaShape,C(1,1),C(1,2),'.')
                text(app.PlasmaShape,C(1,1),C(1,2),'C1  ','HorizontalAlignment','right','FontSize',12)
                plot (app.PlasmaShape,D(1,1),D(1,2),'.')
                text(app.PlasmaShape,D(1,1),D(1,2),'   D1','FontSize',12)
                
                plot(app.PlasmaShape,E(2,1),E(2,2),'.')
                text(app.PlasmaShape,E(2,1),E(2,2),' E2','FontSize',12)
                plot(app.PlasmaShape,Rdiag(2,:),Zdiag(2,:),'k')
                plot (app.PlasmaShape,C(2,1),C(2,2),'.')
                text(app.PlasmaShape,C(2,1),C(2,2),'C2  ','HorizontalAlignment','right','FontSize',12)
                plot (app.PlasmaShape,D(2,1),D(2,2),'.')
                text(app.PlasmaShape,D(2,1),D(2,2),'   D2','FontSize',12)
                
                plot(app.PlasmaShape,E(3,1),E(3,2),'.')
                text(app.PlasmaShape,E(3,1),E(3,2),'  E3','FontSize',12)
                plot(app.PlasmaShape,Rdiag(3,:),Zdiag(3,:),'k')
                plot (app.PlasmaShape,C(3,1),C(3,2),'.')
                text(app.PlasmaShape,C(3,1),C(3,2),'C3  ','HorizontalAlignment','right','FontSize',12)
                plot (app.PlasmaShape,D(3,1),D(3,2),'.')
                text(app.PlasmaShape,D(3,1),D(3,2),'   D3','FontSize',12)
                
                plot(app.PlasmaShape,E(4,1),E(4,2),'.')
                text(app.PlasmaShape,E(4,1),E(4,2),' E4','FontSize',12)
                plot(app.PlasmaShape,Rdiag(4,:),Zdiag(4,:),'k')
                plot (app.PlasmaShape,C(4,1),C(4,2),'.')
                text(app.PlasmaShape,C(4,1),C(4,2),'C4  ','HorizontalAlignment','right','FontSize',12)
                plot (app.PlasmaShape,D(4,1),D(4,2),'.')
                text(app.PlasmaShape,D(4,1),D(4,2),'    D4','FontSize',12)
                
                
                csi(1,1) = (dOD(1)-dOC(1))/(dOE(1)-dOC(1));
                csi(2,1) = (dOD(2)-dOC(2))/(dOE(2)-dOC(2));
                csi(3,1) = (dOD(3)-dOC(3))/(dOE(3)-dOC(3));
                csi(4,1) = (dOD(4)-dOC(4))/(dOE(4)-dOC(4));
                
                app.Squareness1EditField.Value = csi(1,1);
                app.Squareness2EditField.Value = csi(2,1);
                app.Squareness3EditField.Value = csi(3,1);
                app.Squareness4EditField.Value = csi(4,1);
                
                
                [zs_smooth] = ComputeSuperEllipses(app,csi);
                app.Z_limiter = zs_smooth;
                
                % plot superellipses for each quadrant
                plot(app.PlasmaShape,app.r_ellipse(1,:),zs_smooth(1,:),'-k','linewidth',2); %plot first quadrant ellipse
                plot(app.PlasmaShape,app.r_ellipse(2,:),zs_smooth(2,:),'-k','linewidth',2); %plot second quadrant ellipse
                plot(app.PlasmaShape,app.r_ellipse(3,:),zs_smooth(3,:),'-k','linewidth',2);%plot third quadrant ellipse
                plot(app.PlasmaShape,app.r_ellipse(4,:),zs_smooth(4,:),'-k','linewidth',2);%plot fourth quadrant ellipse
                
                
            else
                cla(app.PlasmaShape);
                ComputeshapeparametersButtonPushed(app);
                app.num_points = app.NumpointsEditField.Value;
                
                computeEllipseBoundary(app);
                
                plot(app.PlasmaShape,app.r_ellipse(1,:),app.z_ellipse(1,:),'-r','linewidth',1); %plot first quadrant ellipse
                hold(app.PlasmaShape,'on');
                plot(app.PlasmaShape,app.r_ellipse(2,:),app.z_ellipse(2,:),'-r','linewidth',1); %plot second quadrant ellipse
                plot(app.PlasmaShape,app.r_ellipse(3,:),app.z_ellipse(3,:),'-r','linewidth',1);%plot third quadrant ellipse
                plot(app.PlasmaShape,app.r_ellipse(4,:),app.z_ellipse(4,:),'-r','linewidth',1);%plot fourth quadrant ellipse
                hold(app.PlasmaShape,'off');
            end
        end

        % Button pushed function: UpdateshapeButton
        function UpdateshapeButtonPushed(app, event)
            csi(1,1) = app.Squareness1EditField.Value;
            csi(2,1) = app.Squareness2EditField.Value;
            csi(3,1) = app.Squareness3EditField.Value;
            csi(4,1) = app.Squareness4EditField.Value;
            
            [zs_smooth] = ComputeSuperEllipses(app,csi);
            app.Z_limiter = zs_smooth;
            
            plot(app.PlasmaShape,app.r_ellipse(1,:),zs_smooth(1,:),'c--','linewidth',2); %plot first quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(2,:),zs_smooth(2,:),'c--','linewidth',2); %plot second quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(3,:),zs_smooth(3,:),'c--','linewidth',2);%plot third quadrant ellipse
            plot(app.PlasmaShape,app.r_ellipse(4,:),zs_smooth(4,:),'c--','linewidth',2);%plot fourth quadrant ellipse
            
        end

        % Callback function
        function Squareness1KnobValueChanging(app, event)
            updatePlotCsiKnob1(app);
        end

        % Value changed function: NumpointsEditField
        function NumpointsEditFieldValueChanged(app, event)
            app.num_points = app.NumpointsEditField.Value;
            
        end

        % Selection changed function: XpointsButtonGroup
        function XpointsButtonGroupSelectionChanged(app, event)
            selectedButton = app.XpointsButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Upper Single Null'
                    [zs_Xpoint] = computeSuperellipsesXpoint(app);
                    
                    plot(app.PlasmaShape,app.r_ellipse(1,:),zs_Xpoint(1,:),'b-','linewidth',2); %plot first quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(2,:),zs_Xpoint(2,:),'b-','linewidth',2); %plot second quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(3,:),app.Z_limiter(3,:),'b-','linewidth',2);%plot third quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(4,:),app.Z_limiter(4,:),'b-','linewidth',2);%plot fourth quadrant ellipse
                    
                    app.R_boundary_finale = [app.r_ellipse(1,:),app.r_ellipse(2,:),app.r_ellipse(3,:),app.r_ellipse(4,:)];
                    app.Z_boundary_finale = [zs_Xpoint(1,:) zs_Xpoint(2,:) app.Z_limiter(3,:) app.Z_limiter(4,:)];
                case  'Lower Single Null'
                    [zs_Xpoint] = computeSuperellipsesXpoint(app);
                    
                    plot(app.PlasmaShape,app.r_ellipse(1,:),app.Z_limiter(1,:),'g-','linewidth',2); %plot first quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(2,:),app.Z_limiter(2,:),'g-','linewidth',2); %plot second quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(3,:),zs_Xpoint(3,:),'g-','linewidth',2);%plot third quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(4,:),zs_Xpoint(4,:),'g-','linewidth',2);%plot fourth quadrant ellipse
                    
                    app.R_boundary_finale = [app.r_ellipse(1,:),app.r_ellipse(2,:),app.r_ellipse(3,:),app.r_ellipse(4,:)];
                    app.Z_boundary_finale = [app.Z_limiter(1,:) app.Z_limiter(2,:) zs_Xpoint(3,:) zs_Xpoint(4,:)];
                case  'Double Null'
                    [zs_Xpoint] = computeSuperellipsesXpoint(app);
                    
                    plot(app.PlasmaShape,app.r_ellipse(1,:),zs_Xpoint(1,:),'y-','linewidth',2); %plot first quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(2,:),zs_Xpoint(2,:),'y-','linewidth',2); %plot second quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(3,:),zs_Xpoint(3,:),'y-','linewidth',2);%plot third quadrant ellipse
                    plot(app.PlasmaShape,app.r_ellipse(4,:),zs_Xpoint(4,:),'y-','linewidth',2);%plot fourth quadrant ellipse
                    app.R_boundary_finale = [app.r_ellipse(1,:),app.r_ellipse(2,:),app.r_ellipse(3,:),app.r_ellipse(4,:)];
                    app.Z_boundary_finale = [zs_Xpoint(1,:) zs_Xpoint(2,:) zs_Xpoint(3,:) zs_Xpoint(4,:)];
                    
                case 'None'
                    app.R_boundary_finale = [app.r_ellipse(1,:),app.r_ellipse(2,:),app.r_ellipse(3,:),app.r_ellipse(4,:)];
                    app.Z_boundary_finale = [app.Z_limiter(1,:) app.Z_limiter(2,:) app.Z_limiter(3,:) app.Z_limiter(4,:)];
                    plot(app.PlasmaShape,app.R_boundary_finale,app.Z_boundary_finale,'k-','linewidth',2)
                    
            end
            
            
        end

        % Button pushed function: PlotBoundaryButton
        function PlotBoundaryButtonPushed(app, event)
XpointsButtonGroupSelectionChanged(app);
            cla(app.PlasmaShape)
            plot(app.PlasmaShape,app.R_boundary_finale,app.Z_boundary_finale,'k-','linewidth',2)
            hold(app.PlasmaShape,'on');
            plot(app.PlasmaShape,app.R_boundary_ref,app.Z_boundary_ref,'m--','linewidth',2)
            
        end

        % Value changed function: Squareness1Knob
        function Squareness1KnobValueChanged(app, event)
            updatePlotCsiKnob1(app);
            
        end

        % Value changed function: Squareness2Knob
        function Squareness2KnobValueChanged(app, event)
            updatePlotCsiKnob2(app);
            
            
        end

        % Value changed function: Squareness3Knob
        function Squareness3KnobValueChanged(app, event)
            updatePlotCsiKnob3(app);
            
        end

        % Value changed function: Squareness4Knob
        function Squareness4KnobValueChanged(app, event)
            updatePlotCsiKnob4(app);
            
        end

        % Value changed function: Rou
        function RouValueChanged(app, event)
            cla(app.PlasmaShape);
            app.RZOU(1) = app.Rou.Value;
            app.flagBoundaryExists = 0;
        end

        % Value changed function: Zou
        function ZouValueChanged(app, event)
            app.RZOU(2) = app.Zou.Value;
            app.flagBoundaryExists = 0;
            %             cla(app.PlasmaShape);
            %             plot(app.PlasmaShape,app.RZOU(1),app.RZOU(2),'o');
            %             hold(app.PlasmaShape,'on');
        end

        % Value changed function: Rin
        function RinValueChanged(app, event)
            app.RZIN(1) = app.Rin.Value;
            app.flagBoundaryExists = 0;
        end

        % Value changed function: Zin
        function ZinValueChanged(app, event)
            app.RZIN(2) = app.Zin.Value;
            app.flagBoundaryExists = 0;
        end

        % Value changed function: Rup
        function RupValueChanged(app, event)
            app.flagBoundaryExists = 0;
            
        end

        % Value changed function: Zup
        function ZupValueChanged(app, event)
            app.flagBoundaryExists = 0;
            
        end

        % Value changed function: Rlo
        function RloValueChanged(app, event)
            app.flagBoundaryExists = 0;
            
        end

        % Value changed function: Zlo
        function ZloValueChanged(app, event)
            app.flagBoundaryExists = 0;
            
        end

        % Button pushed function: SaveMeshBoundaryButton
        function SaveMeshBoundaryButtonPushed(app, event)
            OutputShape.p = app.p;
            OutputShape.t = app.t;
            OutputShape.b=app.b;
            OutputShape.R_boundary_finale = app.R_boundary_finale;
            OutputShape.Z_boundary_finale = app.Z_boundary_finale;
            OutputShape.kk_nu = app.kk_nu;
            OutputShape.Area_duale = app.Area_duale;

            uisave({'OutputShape'},'IETShapingToolOutput');
            %uiputfile;
        end

        % Button pushed function: ComputeprimaldualmeshButton
        function ComputeprimaldualmeshButtonPushed(app, event)
            b_box = [app.RZIN(1) - 10,app.RZLO(2) - 10; app.RZOU(1) + 10,app.RZUP(2) + 10];
            h0 = app.InitialedgelengthEditField.Value;
            
            tStart = tic;
            app.TimeMesh.Text = 'Meshing plasma cross section..WAIT...';
            [app.p,app.t]=distmesh2d(@dpoly,@huniform,h0,b_box,[],[app.R_boundary_finale' app.Z_boundary_finale']);
            
            elapsedTime=toc(tStart);
            app.TimeMesh.Text  = ['Required ' num2str(elapsedTime) 'seconds'];


            e = boundedges(app.p,app.t);
            app.b = unique(e);
            cla(app.PlasmaShape);
            triplot(app.t,app.p(:,1),app.p(:,2),'Parent',app.PlasmaShape)
           plot(app.PlasmaShape,app.p(app.b,1),app.p(app.b,2),'k*','markersize',10)
           % legend(app.PlasmaShape,'Boundary','Reference Boundary','Mesh','Boundary Points','Location','NE')
           app.mu0 = 4*pi*1.e-7;
            [app.Area_duale, app.kk_nu] = compute_dual_mesh(app,app.p,app.t,app.mu0);

        end

        % Callback function
        function SaveBoundaryButtonPushed(app, event)
            OutputShape.kk_nu = app.kk_nu;
            OutputShape.Area_duale = app.Area_duale;
            OutputShape.R_boundary_finale = app.R_boundary_finale;
            OutputShape.Z_boundary_finale = app.Z_boundary_finale;
            uisave('OutputShape','IETShapingToolOutput.mat');
        end

        % Callback function
        function LoadFIrstWallGeometryButtonPushed(app, event)
            uiopen('load');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ShapingTab = uitab(app.TabGroup);
            app.ShapingTab.Title = 'Shaping';
            app.PlasmaShape = uiaxes(app.ShapingTab);
            title(app.PlasmaShape, 'Plasma Shape')
            xlabel(app.PlasmaShape, 'R')
            ylabel(app.PlasmaShape, 'Z')
            app.OUTERPanel = uipanel(app.ShapingTab);
            app.OUTERPanel.Title = 'OUTER';
            app.REditFieldLabel = uilabel(app.OUTERPanel);
            app.REditFieldLabel.Text = 'R';
            app.Rou = uieditfield(app.OUTERPanel, 'numeric');
            app.Rou.ValueChangedFcn = createCallbackFcn(app, @RouValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.OUTERPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.Zou = uieditfield(app.OUTERPanel, 'numeric');
            app.Zou.ValueChangedFcn = createCallbackFcn(app, @ZouValueChanged, true);
            app.INNERPanel = uipanel(app.ShapingTab);
            app.INNERPanel.Title = 'INNER';
            app.REditFieldLabel_2 = uilabel(app.INNERPanel);
            app.REditFieldLabel_2.Text = 'R';
            app.Rin = uieditfield(app.INNERPanel, 'numeric');
            app.Rin.ValueChangedFcn = createCallbackFcn(app, @RinValueChanged, true);
            app.ZEditFieldLabel_2 = uilabel(app.INNERPanel);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Zin = uieditfield(app.INNERPanel, 'numeric');
            app.Zin.ValueChangedFcn = createCallbackFcn(app, @ZinValueChanged, true);
            app.UPPERPanel = uipanel(app.ShapingTab);
            app.UPPERPanel.Title = 'UPPER';
            app.REditFieldLabel_3 = uilabel(app.UPPERPanel);
            app.REditFieldLabel_3.Text = 'R';
            app.Rup = uieditfield(app.UPPERPanel, 'numeric');
            app.Rup.ValueChangedFcn = createCallbackFcn(app, @RupValueChanged, true);
            app.ZEditFieldLabel_3 = uilabel(app.UPPERPanel);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.Zup = uieditfield(app.UPPERPanel, 'numeric');
            app.Zup.ValueChangedFcn = createCallbackFcn(app, @ZupValueChanged, true);
            app.LOWERPanel = uipanel(app.ShapingTab);
            app.LOWERPanel.Title = 'LOWER';
            app.REditFieldLabel_4 = uilabel(app.LOWERPanel);
            app.REditFieldLabel_4.Text = 'R';
            app.Rlo = uieditfield(app.LOWERPanel, 'numeric');
            app.Rlo.ValueChangedFcn = createCallbackFcn(app, @RloValueChanged, true);
            app.ZEditFieldLabel_4 = uilabel(app.LOWERPanel);
            app.ZEditFieldLabel_4.Text = 'Z';
            app.Zlo = uieditfield(app.LOWERPanel, 'numeric');
            app.Zlo.ValueChangedFcn = createCallbackFcn(app, @ZloValueChanged, true);
            app.RgeomLabel = uilabel(app.ShapingTab);
            app.RgeomLabel.Text = 'Rgeom';
            app.RgeomEditField = uilabel(app.ShapingTab);
            app.RgeomEditField.Text = 'Rgeom';
            app.ZgeomLabel = uilabel(app.ShapingTab);
            app.ZgeomLabel.Text = 'Zgeom';
            app.ZgeomEditField = uilabel(app.ShapingTab);
            app.ZgeomEditField.Text = 'Zgeom';
            app.ElongationLabel = uilabel(app.ShapingTab);
            app.ElongationLabel.Text = 'Elongation';
            app.ElongationEditField = uilabel(app.ShapingTab);
            app.ElongationEditField.Text = 'k';
            app.UpperElongationLabel = uilabel(app.ShapingTab);
            app.UpperElongationLabel.Text = 'Upper k';
            app.UpperElongationEditField = uilabel(app.ShapingTab);
            app.UpperElongationEditField.Text = 'ku';
            app.LowerElongationLabel = uilabel(app.ShapingTab);
            app.LowerElongationLabel.Text = 'Lower k';
            app.LowerElongationEditField = uilabel(app.ShapingTab);
            app.LowerElongationEditField.Text = 'kl';
            app.TriangularityLabel = uilabel(app.ShapingTab);
            app.TriangularityLabel.Text = 'Triangularity';
            app.TriangularityEditField = uilabel(app.ShapingTab);
            app.TriangularityEditField.Text = 'delta';
            app.UpperTriangularityLabel = uilabel(app.ShapingTab);
            app.UpperTriangularityLabel.Text = 'Upper delta';
            app.UpperTriangularityEditField = uilabel(app.ShapingTab);
            app.UpperTriangularityEditField.Text = 'deltau';
            app.LowerTriangularityLabel = uilabel(app.ShapingTab);
            app.LowerTriangularityLabel.Text = 'Lower delta';
            app.LowerTriangularityEditField = uilabel(app.ShapingTab);
            app.LowerTriangularityEditField.Text = 'deltal';
            app.MinorRadiusLabel = uilabel(app.ShapingTab);
            app.MinorRadiusLabel.Text = 'Minor radius';
            app.MinorRadiusEditField = uilabel(app.ShapingTab);
            app.MinorRadiusEditField.Text = 'Rgeom';
            app.HorizontalElongationLabel = uilabel(app.ShapingTab);
            app.HorizontalElongationLabel.Text = 'Horizontal Elongation';
            app.HorizontalElongationEditField = uilabel(app.ShapingTab);
            app.HorizontalElongationEditField.Text = 'k';
            app.LoadReferencePlasmaBoundaryButton = uibutton(app.ShapingTab, 'push');
            app.LoadReferencePlasmaBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferencePlasmaBoundaryButtonPushed, true);
            app.LoadReferencePlasmaBoundaryButton.Text = 'Load Reference Plasma Boundary ';
            app.GenerateBoundaryEllipse = uibutton(app.ShapingTab, 'push');
            app.GenerateBoundaryEllipse.ButtonPushedFcn = createCallbackFcn(app, @GenerateBoundaryEllipsePushed, true);
            app.GenerateBoundaryEllipse.Text = 'Generate elliptic boundary';
            app.NumpointsEditFieldLabel = uilabel(app.ShapingTab);
            app.NumpointsEditFieldLabel.Text = 'Num points';
            app.NumpointsEditField = uieditfield(app.ShapingTab, 'numeric');
            app.NumpointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumpointsEditFieldValueChanged, true);
            app.NumpointsEditField.Value = 100;
            app.ComputeshapeparametersButton = uibutton(app.ShapingTab, 'push');
            app.ComputeshapeparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeshapeparametersButtonPushed, true);
            app.ComputeshapeparametersButton.Text = 'Compute shape parameters';
            app.XpointsButtonGroup = uibuttongroup(app.ShapingTab);
            app.XpointsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XpointsButtonGroupSelectionChanged, true);
            app.XpointsButtonGroup.Title = 'X-points';
            app.UpperSingleNullButton = uiradiobutton(app.XpointsButtonGroup);
            app.UpperSingleNullButton.Text = 'Upper Single Null';
            app.LowerSingleNullButton = uiradiobutton(app.XpointsButtonGroup);
            app.LowerSingleNullButton.Text = 'Lower Single Null';
            app.DoubleNullButton = uiradiobutton(app.XpointsButtonGroup);
            app.DoubleNullButton.Text = 'Double Null';
            app.NoneButton = uiradiobutton(app.XpointsButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.PlotBoundaryButton = uibutton(app.XpointsButtonGroup, 'push');
            app.PlotBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBoundaryButtonPushed, true);
            app.PlotBoundaryButton.Text = 'Plot Boundary';
            app.A1Label = uilabel(app.ShapingTab);
            app.A1Label.Text = 'A1';
            app.B1Label = uilabel(app.ShapingTab);
            app.B1Label.Text = 'B1';
            app.n1Label = uilabel(app.ShapingTab);
            app.n1Label.Text = 'n1';
            app.A2Label = uilabel(app.ShapingTab);
            app.A2Label.Text = 'A2';
            app.B2Label = uilabel(app.ShapingTab);
            app.B2Label.Text = 'B2';
            app.n2Label = uilabel(app.ShapingTab);
            app.n2Label.Text = 'n2';
            app.A3Label = uilabel(app.ShapingTab);
            app.A3Label.Text = 'A3';
            app.B3Label = uilabel(app.ShapingTab);
            app.B3Label.Text = 'B3';
            app.n3Label = uilabel(app.ShapingTab);
            app.n3Label.Text = 'n3';
            app.A4Label = uilabel(app.ShapingTab);
            app.A4Label.Text = 'A4';
            app.B4Label = uilabel(app.ShapingTab);
            app.B4Label.Text = 'B4';
            app.n4Label = uilabel(app.ShapingTab);
            app.n4Label.Text = 'n4';
            app.NoQuadrantLabel = uilabel(app.ShapingTab);
            app.NoQuadrantLabel.Text = 'No. Quadrant';
            app.HorizontalAxisLabel = uilabel(app.ShapingTab);
            app.HorizontalAxisLabel.Text = 'Horizontal Axis';
            app.VerticalAxisLabel = uilabel(app.ShapingTab);
            app.VerticalAxisLabel.Text = 'Vertical Axis';
            app.ExponentLabel = uilabel(app.ShapingTab);
            app.ExponentLabel.Text = 'Exponent';
            app.ILabel = uilabel(app.ShapingTab);
            app.ILabel.Text = 'I';
            app.IILabel = uilabel(app.ShapingTab);
            app.IILabel.Text = 'II';
            app.IIILabel = uilabel(app.ShapingTab);
            app.IIILabel.Text = 'III';
            app.IVLabel = uilabel(app.ShapingTab);
            app.IVLabel.Text = 'IV';
            app.MeshparametersPanel = uipanel(app.ShapingTab);
            app.MeshparametersPanel.Title = 'Mesh parameters';
            app.TimeMesh = uilabel(app.MeshparametersPanel);
            app.InfoLabel = uilabel(app.MeshparametersPanel);
            app.InfoLabel.Text = 'Info:';
            app.InitialedgelengthEditField_3Label = uilabel(app.MeshparametersPanel);
            app.InitialedgelengthEditField_3Label.Text = 'Initial edge length';
            app.InitialedgelengthEditField = uieditfield(app.MeshparametersPanel, 'numeric');
            app.InitialedgelengthEditField.Limits = [0.01 1];
            app.InitialedgelengthEditField.Value = 0.1;
            app.ComputeprimaldualmeshButton = uibutton(app.MeshparametersPanel, 'push');
            app.ComputeprimaldualmeshButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeprimaldualmeshButtonPushed, true);
            app.ComputeprimaldualmeshButton.Text = {'Compute '; 'primal & dual mesh'};
            app.SaveMeshBoundaryButton = uibutton(app.MeshparametersPanel, 'push');
            app.SaveMeshBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMeshBoundaryButtonPushed, true);
            app.SaveMeshBoundaryButton.Text = 'Save Mesh & Boundary';
            app.ShapingControlPanel = uipanel(app.ShapingTab);
            app.ShapingControlPanel.Title = 'Shaping Control Panel';
            app.ShapeControlSwitchLabel = uilabel(app.ShapingControlPanel);
            app.ShapeControlSwitchLabel.Text = 'Shape Control';
            app.ShapeControlSwitch = uiswitch(app.ShapingControlPanel, 'toggle');
            app.ShapeControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ShapeControlSwitchValueChanged, true);
            app.Squareness1EditFieldLabel = uilabel(app.ShapingControlPanel);
            app.Squareness1EditFieldLabel.Text = 'Squareness 1';
            app.Squareness1EditField = uieditfield(app.ShapingControlPanel, 'numeric');
            app.Squareness1EditField.Limits = [-2 1];
            app.Squareness2EditFieldLabel = uilabel(app.ShapingControlPanel);
            app.Squareness2EditFieldLabel.Text = 'Squareness 2';
            app.Squareness2EditField = uieditfield(app.ShapingControlPanel, 'numeric');
            app.Squareness2EditField.Limits = [-2 1];
            app.Squareness3EditFieldLabel = uilabel(app.ShapingControlPanel);
            app.Squareness3EditFieldLabel.Text = 'Squareness 3';
            app.Squareness3EditField = uieditfield(app.ShapingControlPanel, 'numeric');
            app.Squareness3EditField.Limits = [-2 1];
            app.Squareness4EditFieldLabel = uilabel(app.ShapingControlPanel);
            app.Squareness4EditFieldLabel.Text = 'Squareness 4';
            app.Squareness4EditField = uieditfield(app.ShapingControlPanel, 'numeric');
            app.Squareness4EditField.Limits = [-2 1];
            app.UpdateshapeButton = uibutton(app.ShapingControlPanel, 'push');
            app.UpdateshapeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateshapeButtonPushed, true);
            app.UpdateshapeButton.Text = 'Update shape';
            app.Squareness1KnobLabel = uilabel(app.ShapingControlPanel);
            app.Squareness1KnobLabel.Text = 'Squareness 1';
            app.Squareness1Knob = uiknob(app.ShapingControlPanel, 'continuous');
            app.Squareness1Knob.Limits = [-2 1];
            app.Squareness1Knob.ValueChangedFcn = createCallbackFcn(app, @Squareness1KnobValueChanged, true);
            app.Squareness2KnobLabel = uilabel(app.ShapingControlPanel);
            app.Squareness2KnobLabel.Text = 'Squareness 2';
            app.Squareness2Knob = uiknob(app.ShapingControlPanel, 'continuous');
            app.Squareness2Knob.Limits = [-2 1];
            app.Squareness2Knob.ValueChangedFcn = createCallbackFcn(app, @Squareness2KnobValueChanged, true);
            app.Squareness3KnobLabel = uilabel(app.ShapingControlPanel);
            app.Squareness3KnobLabel.Text = 'Squareness 3';
            app.Squareness3Knob = uiknob(app.ShapingControlPanel, 'continuous');
            app.Squareness3Knob.Limits = [-2 1];
            app.Squareness3Knob.ValueChangedFcn = createCallbackFcn(app, @Squareness3KnobValueChanged, true);
            app.Squareness4KnobLabel = uilabel(app.ShapingControlPanel);
            app.Squareness4KnobLabel.Text = 'Squareness 4';
            app.Squareness4Knob = uiknob(app.ShapingControlPanel, 'continuous');
            app.Squareness4Knob.Limits = [-2 1];
            app.Squareness4Knob.ValueChangedFcn = createCallbackFcn(app, @Squareness4KnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IET_SHAPING_TOOL_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end